classdef MYKI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WelcomeLabel                   matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ClassifierParametersTab        matlab.ui.container.Tab
        FeaturessettingsPanel          matlab.ui.container.Panel
        PostprocessingalgorithmButtonGroup  matlab.ui.container.ButtonGroup
        GrasplockButton                matlab.ui.control.RadioButton
        MajorityvoteButton             matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        AllowpatternrecognitionButton  matlab.ui.control.StateButton
        WaitforMVEditField             matlab.ui.control.NumericEditField
        WaitforMV150msEditFieldLabel   matlab.ui.control.Label
        WaitforODAEditField            matlab.ui.control.NumericEditField
        WaitforODA150msEditField_2Label  matlab.ui.control.Label
        FeaturesPanel                  matlab.ui.container.Panel
        LastvalueCheckBox              matlab.ui.control.CheckBox
        SlopeCheckBox                  matlab.ui.control.CheckBox
        WLCheckBox                     matlab.ui.control.CheckBox
        MinCheckBox                    matlab.ui.control.CheckBox
        MaxCheckBox                    matlab.ui.control.CheckBox
        MedianCheckBox                 matlab.ui.control.CheckBox
        MAVCheckBox                    matlab.ui.control.CheckBox
        SendfeatparamButton            matlab.ui.control.Button
        OverlapwindowsamplesEditField  matlab.ui.control.EditField
        OverlapwindowsamplesEditFieldLabel  matlab.ui.control.Label
        TimewindowmsEditField          matlab.ui.control.EditField
        TimewindowmsEditFieldLabel     matlab.ui.control.Label
        PatternRecognitionClassifierPanel  matlab.ui.container.Panel
        UploadpropctrlparamButton      matlab.ui.control.Button
        ProportionalcontrolCheckBox    matlab.ui.control.CheckBox
        UploadtoboardButton            matlab.ui.control.Button
        NumclassesLabel                matlab.ui.control.Label
        MagFeaturesLabel               matlab.ui.control.Label
        FeaturesLabel                  matlab.ui.control.Label
        ClassifierLabel                matlab.ui.control.Label
        LoadfromfileButton             matlab.ui.control.Button
        ConnectionPanel                matlab.ui.container.Panel
        ChooseCOMDropDown              matlab.ui.control.DropDown
        ChooseCOMDropDownLabel         matlab.ui.control.Label
        RefreshCOMButton               matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        StreamingTab                   matlab.ui.container.Tab
        PPTypeLabel                    matlab.ui.control.Label
        PostprocessingalgorithmLabel   matlab.ui.control.Label
        dist3Label                     matlab.ui.control.Label
        dist2Label                     matlab.ui.control.Label
        dist1Label                     matlab.ui.control.Label
        TypeLabel                      matlab.ui.control.Label
        ClassifierLabel_2              matlab.ui.control.Label
        ForceLabel                     matlab.ui.control.Label
        ProportionalcontrolLabel       matlab.ui.control.Label
        ClassLabel                     matlab.ui.control.Label
        PredictedclassLabel            matlab.ui.control.Label
        StreamButton                   matlab.ui.control.StateButton
        RestandMaxdispTab              matlab.ui.container.Tab
        UploadMagnetPairsButton        matlab.ui.control.Button
        UploadDataButton               matlab.ui.control.Button
        MaximumDisplacement            matlab.ui.control.Label
        MaximumdisplacementLabel       matlab.ui.control.Label
        RestDistance                   matlab.ui.control.Label
        RestdistanceLabel              matlab.ui.control.Label
        MagnetPairs                    matlab.ui.control.Label
        MagnetPairsLabel               matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        MultilinearRegressorTab        matlab.ui.container.Tab
        UploadparametersButton         matlab.ui.control.Button
        TestMLRButton                  matlab.ui.control.Button
        TrainMLRButton                 matlab.ui.control.Button
        DoFListBox                     matlab.ui.control.ListBox
        DoFListBoxLabel                matlab.ui.control.Label
        FeaturesListBox                matlab.ui.control.ListBox
        FeaturesListBoxLabel           matlab.ui.control.Label
        LoadfeaturesButton             matlab.ui.control.Button
    end

    properties
        obj
        patRec
        sigFeat
        dataRestMax
        diagBox
    end
    properties(Constant)
        % 9 is constant, the second 9 is magPairs, 12 is 6*2 where 6 is the num of
        % features and 2 is for magent features, 5 is the number of
        % classes, 4 is the bytes for float
        msgLength = 14 + 9*2 + 9*12*4*2 +  5*4 + 5*9*12*4; 
    end

    methods (Access = public)
        function UpdateProstheticOutput(app, array)
            app.patRec.prostheticOutput = array;
        end
    end

    methods (Access = private)
        
        function SendCommand(app,command)
            comm = ['@', command, '#'];
            for i = 1:length(comm)
                write(app.obj,comm(i),'char');
            end
        end

        function array = Splitfloat(app,doubleArray) % split an array of float32 into an array of 4 uint8
            array = uint8(zeros(1,length(doubleArray)*4));
            for i = 1:length(doubleArray)
                temp = typecast(single(doubleArray(i)),'uint8');
                array((1:4)+4*(i-1)) = temp(end:-1:1);
%                 array((1:4)+4*(i-1)) = temp;
            end
        end

        function UpdateFeatureCheckbox(app)
            app.MAVCheckBox.Value = 0;
            app.MedianCheckBox.Value = 0;
            app.MaxCheckBox.Value = 0;
            app.MinCheckBox.Value = 0;
            app.WLCheckBox.Value = 0;
            app.SlopeCheckBox.Value = 0;
            app.LastvalueCheckBox.Value = 0;
            if(sum(strcmp(app.patRec.selFeatures,'tmabsTr1')))
                app.MAVCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'tmd')))
                app.MedianCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'tmax')))
                app.MaxCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'tmin')))
                app.MinCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'twl')))
                app.WLCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'mslp')))
                app.SlopeCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'mlv')))
                app.LastvalueCheckBox.Value = 1;
            end
        end

        function featureCode = CreateFeatureCode(app)
            featureCode = uint8(0); 
            if sum(contains(app.patRec.selFeatures,'tmabsTr1'))
                featureCode = bitor(featureCode,8);
            end
            if sum(contains(app.patRec.selFeatures,'tmd'))
                featureCode = bitor(featureCode,1);
            end
            if sum(contains(app.patRec.selFeatures,'tmax'))
                featureCode = bitor(featureCode,2);
            end
            if sum(contains(app.patRec.selFeatures,'tmin'))
                featureCode = bitor(featureCode,4);
            end
            if sum(contains(app.patRec.selFeatures,'twl'))
                featureCode = bitor(featureCode,16);
            end
            if sum(contains(app.patRec.selFeatures,'mslp'))
                featureCode = bitor(featureCode,32);
            end
            if sum(contains(app.patRec.selFeatures,'mlv'))
                featureCode = bitor(featureCode,64);
            end
        end

        function EnableDisableAllCheckBoxes(app, value)
            if value
                str = "on";
            else
                str = "off";
            end
            app.MAVCheckBox.Enable    = str;
            app.MedianCheckBox.Enable = str;
            app.MaxCheckBox.Enable    = str;
            app.MinCheckBox.Enable    = str;
            app.WLCheckBox.Enable     = str;
            app.SlopeCheckBox.Enable  = str;
            app.LastvalueCheckBox.Enable = str;
        end

        function classStr = InterpretClass(app,class)
            switch (class)
                case 3
                    classStr = "Close hand";
                case 5
                    classStr = "Open hand";
                case 4
                    classStr = "Bidigital grasp";
                case 6
                    classStr = "Lateral grasp";
                case 0
                    classStr = "Rest";
                otherwise
                    classStr = "Not a class";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseCOMDropDown
        function ChooseCOMDropDownValueChanged(app, event)
            value = app.ChooseCOMDropDown.Value;
            
        end

        % Drop down opening function: ChooseCOMDropDown
        function ChooseCOMDropDownOpening(app, event)
            ports = serialportlist;
            if ~isempty(ports)
                app.ChooseCOMDropDown.Items = ports;
            else
                app.ChooseCOMDropDown.Items = {'None Available'};
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            ComPortName = app.ChooseCOMDropDown.Value;
            if ~contains(ComPortName,'COM')
                app.WelcomeLabel.Text = 'Invalid COM port';
                drawnow();
                return
            end
            app.WelcomeLabel.Text = 'Testing Connection...';
            drawnow();
            BaudRate = 921600;
            delete(instrfindall);
            if isempty(app.obj)
                app.obj = serialport(ComPortName, BaudRate, 'Databits', 8,'Byteorder', 'big-endian');
            end
%             app.obj = serial(ComPortName, 'baudrate', BaudRate, 'databits', 8, 'byteorder', 'bigEndian');
%             fclose(app.obj);
%             fopen(app.obj);
            flush(app.obj);
            % stop the streaming
            SendCommand(app,'A0');
            SendCommand(app,'B0');
            pause(0.5)
            app.WelcomeLabel.Text = 'Connection established';
%             fclose(app.obj);
        end

        % Button pushed function: RefreshCOMButton
        function RefreshCOMButtonPushed(app, event)
            ports = serialportlist;
            if ~isempty(ports)
                % Show all available ports
                app.ChooseCOMDropDown.Items = ports;
            else
                app.ChooseCOMDropDown.Items = {'None Available'};
            end
        end

        % Button pushed function: LoadfromfileButton
        function LoadfromfileButtonPushed(app, event)
            app.WelcomeLabel.Text = 'Loading parameters file...';
            [file, path] = uigetfile({'*.mat';'*.csv'});
            if ~isequal(file, 0)
                load([path,file]); % load patRec file 
                if exist('patRec','var')
                    app.patRec = patRec;
                    if strcmp(app.patRec.patRecTrained.algorithm,'SVMt')
                        app.ClassifierLabel.Text = 'SVM transient';
                    elseif contains(app.patRec.patRecTrained.algorithm,'SVM')
                        app.ClassifierLabel.Text = 'SVM steady state';
                    else
                        app.ClassifierLabel.Text = app.patRec.patRecTrained.algorithm;
                    end
                    str = [];
                    for i = 1:length(app.patRec.selFeatures)
                        str = [str, app.patRec.selFeatures{i},', '];
                    end
                    str(end-1:end) = [];
                    app.FeaturesLabel.Text = str; % features used
                    if (length(app.patRec.magnetData.Features) == 2)
                        % magnet features used
                        app.MagFeaturesLabel.Text = 'Lin. and Ang. displacement';
                    else
                        app.MagFeaturesLabel.Text = app.patRec.magnetData.Features{1};
                    end
                    app.NumclassesLabel.Text = [num2str(app.patRec.nM), ' classes'];
                    app.WelcomeLabel.Text = 'Loaded PatRec';
                    UpdateFeatureCheckbox(app);
                    EnableDisableAllCheckBoxes(app,0);
                    % In case steady state SVM with ODA is chosen, it is
                    % reaquired to load also the file with ODA thresholds
                    % obtained with tSVM
%                     if (app.SteadyStateODAButton.Value)
%                         uiwait(msgbox("Choose file with thresholds for ODA","Success","modal"));
% %                         msgbox("Choose file with thresholds for ODA");
%                         [file, path] = uigetfile({'*.mat';'*.csv'});
%                         if ~isequal(file, 0)
%                            thr = load([path,file]);
%                            app.patRec.dThreshold = thr.dThreshold;
%                            app.patRec.threshold = thr.threshold;
%                         end
%                     end
                    app.UploadtoboardButton.Enable = "on";
                    app.UploadpropctrlparamButton.Enable = "on";
                    app.TimewindowmsEditField.Value = num2str(app.patRec.tW*1000);
                else
                    app.WelcomeLabel.Text = 'Wrong file!';
                end
            end
        end

        % Button pushed function: UploadtoboardButton
        function UploadtoboardButtonPushed(app, event)
            if isempty(app.patRec)
                app.WelcomeLabel.Text = "PatRec is not loaded!";
                return
            end
            if ~isfield(app.patRec,'prostheticOutput')
                app.diagBox = diagApp(app);
                waitfor(app.diagBox);
            end
            if ~isfield(app.patRec,'dThreshold')
                app.patRec.dThreshold = 1;
                app.patRec.threshold = 0;
            end
            nPairs = uint8(size(app.patRec.magnetData.pairs,1));
            nFeat  = uint8(length(app.patRec.selFeatures));
            nCh    = uint8(length(app.patRec.nCh));
            magPairs = uint8(reshape(app.patRec.magnetData.pairs',1,2*nPairs));
            prostheticOut = uint8(app.patRec.prostheticOutput');
            if strcmp(app.patRec.normSets.type,'Midrange0Range2')
                normParameter1 = app.patRec.normSets.midrange;
                normParameter2 = app.patRec.normSets.range;
                normParameter1 = Splitfloat(app,normParameter1);
                normParameter2 = Splitfloat(app,normParameter2);
            end
            % Form message for different classifiers
%             if (app.TransientButton.Value || app.SteadyStateODAButton.Value)
                msgL = app.msgLength + 8; % + 8 is for the 2 ODA thresholds which are float
                header = 'PRT';
                classes = uint8(app.patRec.nM);
                if strcmp(app.patRec.patRecTrained.algorithm,'SVMt')
                    classifierCode = uint8(2);
                    nM = uint8(app.patRec.nM) - 1; % transient doesn't classify rest
%                     prostheticOut(end) = [];
                else
                    classifierCode = uint8(1);
                    nM = uint8(app.patRec.nM);
                end
                bias = zeros(1,nM);
                w    = zeros(1,nM*nFeat*nCh);
                for i = 1:nM
                    % put classifier parameters in an array 
                    bias(i) = app.patRec.patRecTrained.SVM{i}.Bias;
                    w((1:nFeat*nCh)+nFeat*nCh*(i-1)) = app.patRec.patRecTrained.SVM{i}.Beta';
                end
                % change the classifier parameters from float to 4 uint8
                % bytes, so they can be transfered
                bias = Splitfloat(app,bias);
                w = Splitfloat(app,w);
                dThreshold = Splitfloat(app,app.patRec.dThreshold);
                threshold  = Splitfloat(app,app.patRec.threshold);
                featureCodeMag = uint8(0); % this value contains which featuers for magnets are used
                if (length(app.patRec.magnetData.Features) == 2)
                    featureCodeMag = bitor(featureCodeMag,2^7+2^6);
                elseif contains(app.patRec.magnetData.Features{1},'Lin')
                    featureCodeMag = bitor(featureCodeMag,2^7);
                else
                    app.WelcomeLabel.Text = 'Error: Linear distance is not included!';
                    return;
                end
                featureCode = CreateFeatureCode(app); % this value contains which featuers are used for pattern recognition
                % creating a command to be sent
                command = [header, classifierCode, classes, featureCodeMag, featureCode, uint8(nFeat*nCh), ...
                    prostheticOut, nPairs, magPairs, normParameter1,...
                    normParameter2, dThreshold, threshold, bias, w];
                if length(command)~=msgL
                    command = [command, uint8(zeros(1,msgL-length(command)))];
                end
%             elseif app.SteadyStateMVButton.Value
%                 msgL = 551 + 5; % the message is smaller because only one featuer is used
%                 header = 'PRS';
%                 classes = uint8(app.patRec.nM);
%                 bias = zeros(1,classes);
%                 w    = zeros(1,classes*nFeat*nCh);
%                 for i = 1:classes
%                     bias(i) = app.patRec.patRecTrained.SVM{i}.Bias;
%                     w((1:nFeat*nCh)+nFeat*nCh*(i-1)) = app.patRec.patRecTrained.SVM{i}.Beta';
%                 end
%                 bias = Splitfloat(app,bias);
%                 w = Splitfloat(app,w);
%                 featureCode = uint8(0); % 7th and 6th bit are lin and angluar disp., 0, 1st and 2nd bit are median, max and min 
%                 featureCodeMag = uint8(0);
%                 if (length(app.patRec.magnetData.Features) == 2)
%                     featureCodeMag = bitor(featureCodeMag,2^7+2^6);
%                 elseif contains(app.patRec.magnetData.Features{1},'Lin')
%                     featureCodeMag = bitor(featureCodeMag,2^7);
%                 elseif contains(app.patRec.magnetData.Features{1},'Ang')
%                     featureCodeMag = bitor(featureCodeMag,2^6);
%                 end
%                 if sum(contains(app.patRec.selFeatures,'mlv'))
%                     featureCode = bitor(featureCode,64);
%                 end
%                 command = [header, classes, featureCodeMag, featureCode, uint8(nFeat*nCh), prostheticOut,...
%                     nPairs, magPairs, normParameter1, normParameter2, bias, w];
%                 if length(command)~=msgL
%                     command = [command, uint8(zeros(1,msgL-length(command)))];
%                 end
%             end
            if ~isempty(app.obj)
                SendCommand(app,command);
                app.WelcomeLabel.Text = 'Classifier parameters sent';
            else
                app.WelcomeLabel.Text = 'No connection!';
            end
        end

        % Button pushed function: SendfeatparamButton
        function SendfeatparamButtonPushed(app, event)
            tW = str2num(app.TimewindowmsEditField.Value);
            tW = uint8(tW/50); % minimum time window is 50ms
            oW = uint8(str2num(app.OverlapwindowsamplesEditField.Value));
            waitODA = uint8(app.WaitforODAEditField.Value);
            waitMV  = uint8(app.WaitforMVEditField.Value);
            featCode = CreateFeatureCode(app);
            postProcessMode = uint8(0);
            if app.MajorityvoteButton.Value
                postProcessMode = uint8(1);
            elseif app.GrasplockButton.Value
                postProcessMode = uint8(2);
            end
%             if app.SteadyStateODAButton.Value || app.TransientButton.Value
%                 classifierCode = uint8(1+app.TransientButton.Value);
%                 featCode = CreateFeatureCode(app);
%             else
%                 classifierCode = uint8(3);
%                 featCode = featCode + 2^6;
%             end
            header = 'PRF';
            command = [header, tW, oW, waitODA, waitMV, postProcessMode, featCode];
            SendCommand(app,command);
            app.WelcomeLabel.Text = 'Feature parameters are sent!';
        end

        % Callback function
        function ChooseclassifiermodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseclassifiermodeButtonGroup.SelectedObject;
            if selectedButton == app.SteadyStateMVButton
                app.LastvalueCheckBox.Value  = 1;
                EnableDisableAllCheckBoxes(app,0);
                app.TimewindowmsEditField.Value = "50";
            else
                EnableDisableAllCheckBoxes(app,1);
                app.TimewindowmsEditField.Value = "200";
            end
        end

        % Button pushed function: UploadpropctrlparamButton
        function UploadpropctrlparamButtonPushed(app, event)
            flagPC = uint8(app.ProportionalcontrolCheckBox.Value);
            header = 'PRP';
%             if (flagPC)
%             else
%                 command = [header, 0];
%             end
            command = [header, flagPC];
            SendCommand(app,command);
        end

        % Value changed function: StreamButton
        function StreamButtonValueChanged(app, event)
            value = app.StreamButton.Value;
            if value
%                 SendCommand(app,'B1');
                pause(0.5);
%                 SendCommand(app,'A1');
                % wait for streaming to start
                pause(0.5);
                app.WelcomeLabel.Text = "Streaming started";
                drawnow;
                while(app.StreamButton.Value)
%                     data = [data, read(app.obj,5,'char')];
                    % wait for the part realted to pattern recognition
                    msg = read(app.obj,150,'char');
                    idx1 = strfind(msg,'M');
                    idx = strfind(msg,'pr');
                    if ~isempty(idx1)
%                         idx1 = idx1(1);
%                         if (idx1<130)
%                             data2 = uint8(msg((idx1+1):(idx1+3)));
%                             app.dist1Label.Text = num2str(data2(1));
%                             app.dist2Label.Text = num2str(data2(2));
%                             app.dist3Label.Text = num2str(data2(3));
%                         end
%                         drawnow;
                    end
                    if (~isempty(idx))
                        idx = idx(1)+2;
                        if(idx < 140)
                        data = uint8(msg((idx):(idx+3)));
                        data1 = int8(msg((idx+4):(idx+6)));
                        switch data(1)
                            case 1
                                str = "SVM steady state";
                            case 2
                                str = "SVM transient";
                            case 3
                                str = "SVM steady state + MV";
                            otherwise
                                str = num2str(data(1));
                        end
                        app.TypeLabel.Text = str;
                        class = InterpretClass(app,data(2));
%                         class = num2str(data(2));
                        app.ClassLabel.Text = class;
                        switch data(4)
                            case 0
                                str1 = "NONE";
                            case 1
                                str1 = "Majority vote";
                            case 2
                                str1 = "Grasp lock";
                            otherwise
                                str1 = num2str(data(4));
                        end
                        app.PPTypeLabel.Text = str1;
                        app.ForceLabel.Text = num2str(data(3));
                        app.dist1Label.Text = num2str(data1(1));
                        app.dist2Label.Text = num2str(data1(2));
                        app.dist3Label.Text = num2str(data1(3));
                        drawnow;
                        end
                    end
                end
%                 writematrix(data,'data.txt');
            else
                SendCommand(app,'B0');
                pause(0.5);
                SendCommand(app,'A0');
                app.WelcomeLabel.Text = "Streaming stopped";
                app.dist1Label.Text = '';
                app.dist2Label.Text = '';
                app.dist3Label.Text = '';
                drawnow;
            end
        end

        % Value changed function: AllowpatternrecognitionButton
        function AllowpatternrecognitionButtonValueChanged(app, event)
            value = app.AllowpatternrecognitionButton.Value;
            if value 
                SendCommand(app,'PRA1');
                app.WelcomeLabel.Text = 'Pattern recognition allowed';
            else
                SendCommand(app,'PRA0');
                app.WelcomeLabel.Text = "Pattern recognition stopped";
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.WelcomeLabel.Text = 'Loading data from file...';
            [file, path] = uigetfile({'*.mat';'*.csv'});
            if ~isequal(file, 0)
                data = load([path,file]); 
                if isfield(data,'magPairs')
                    magPairsStr = ["Pair: (Mag1, Mag2)"];
                    dRestStr = ["Pair: distance"];
                    dMaxDispStr = ["Pair: displacement"];
                    for i = 1:size(data.magPairs,1)
                        magPairsStr = [magPairsStr, num2str(i) + ": (" + num2str(data.magPairs(i,1))...
                            + ", " + num2str(data.magPairs(i,2)) + ")"];
                        dRestStr = [dRestStr, num2str(i) + ": " + num2str(data.d_rest(i))];
                        if (isfield(data,'displ_max'))
                            dMaxDispStr = [dMaxDispStr, num2str(i) + ": " + num2str(data.displ_max(i))];
                        else
                            dMaxDispStr = [dMaxDispStr, num2str(i) + ": 1"];
                        end
                    end
                    app.MagnetPairs.Text = magPairsStr;
                    app.RestDistance.Text = dRestStr;
                    app.MaximumDisplacement.Text = dMaxDispStr;
                    app.WelcomeLabel.Text = 'File loaded';
%                     app.UploadDataButton.Enable = "on";
                    app.UploadMagnetPairsButton.Enable = "on";
                    app.dataRestMax = data;
                else
                    app.WelcomeLabel.Text = "Error: wrong file!";
                    return;
                end
            else
                app.WelcomeLabel.Text = "Error: file not chosen!";
            end
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            data = app.dataRestMax;
            if (~isfield(data,'displ_max'))
                data.displ_max = ones(1,length(data.d_rest));
            end
            header = 'hc';
            d_rest_char = [];
            d_max_char = [];
            for i = 1:length(data.d_rest)
                d_rest_char = [d_rest_char, num2str(floor(data.d_rest(i))), num2str(floor(data.d_rest(i)*10)),...
                    num2str(floor(data.d_rest(i)*100)), num2str(floor(data.d_rest(i)*1000))];
                d_max_char = [d_max_char, num2str(floor(data.displ_max(i))), num2str(floor(data.displ_max(i)*10)),...
                    num2str(floor(data.displ_max(i)*100)), num2str(floor(data.displ_max(i)*1000))];
            end
            command = [header, d_rest_char, d_max_char];
            if (isempty(app.obj))
                app.WelcomeLabel.Text = "Error: port not open!";
                return;
            end
            SendCommand(app,command);
            app.WelcomeLabel.Text = "Data sent";
        end

        % Button pushed function: UploadMagnetPairsButton
        function UploadMagnetPairsButtonPushed(app, event)
            if (~isfield(app.dataRestMax,'displ_max'))
                displ_max = ones(1,length(app.dataRestMax.d_rest));
            end
            nPairs = uint8(size(app.dataRestMax.magPairs,1));
            magPairs = reshape(app.dataRestMax.magPairs',1,nPairs*2);
            magPairs = uint8(magPairs);
            dR = app.dataRestMax.d_rest;
            dR = Splitfloat(app,dR*1000);
            dM = Splitfloat(app,displ_max);
            header = 'PRM';
            command = [header, nPairs, magPairs, dR, dM];
            SendCommand(app,command);
            app.WelcomeLabel.Text = "Magnet pairs sent";
        end

        % Button pushed function: LoadfeaturesButton
        function LoadfeaturesButtonPushed(app, event)
            app.WelcomeLabel.Text = 'Loading features file...';
            [file, path] = uigetfile({'*.mat';'*.csv'});
            if ~isequal(file, 0)
                feat = load([path,file]); % load patRec file 
                if isfield(feat,'sigFeatures')
                    app.sigFeat = feat.sigFeatures;
                    allFeatures = fieldnames(app.sigFeat.trFeatures);
                    app.FeaturesListBox.Items = allFeatures;
                    app.FeaturesListBox.Value = allFeatures;
                    app.DoFListBox.Items = app.sigFeat.mov;
                    app.DoFListBox.Value = app.sigFeat.mov;
                    app.TrainMLRButton.Enable = "on";
                else
                    app.WelcomeLabel.Text = 'Error: Wrong file!';
                end
            end
        end

        % Button pushed function: TrainMLRButton
        function TrainMLRButtonPushed(app, event)
            features = app.FeaturesListBox.Value;
            dofs = app.DoFListBox.Value;
            feat = app.sigFeat;
            for i = 1:length(dofs)
                dofsNum(i) = find(strcmp(app.DoFListBox.Items,dofs{i}));
            end
            numSamples = size(feat.transFeatures,1);
            nCh = length(feat.nCh) - 1;
            data = zeros(numSamples,nCh*length(features),length(dofsNum));
            labels = zeros(numSamples,length(dofsNum));
            for i = 1:numSamples
                for j = 1:length(dofsNum)
                    for k = 1:length(features)
                        kidx = (1:nCh) + (k-1)*nCh;
                        temp = feat.transFeatures(i,dofsNum(j)).(features{k});
                        data(i,kidx,j) = temp(1:end-1);
                    end
                    labels(i,j) = feat.transFeatures(i,dofsNum(j)).tmabs(end);
                end
            end
            maxD = max(data);
            minD = min(data);
            range = maxD - minD;
            midRange = (maxD + minD)./2;
            dataNorm = (data - midRange)./(range./2);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClassifierParametersTab = uitab(app.TabGroup);
            app.ClassifierParametersTab.Title = 'Classifier Parameters';
            app.ConnectionPanel = uipanel(app.ClassifierParametersTab);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Tag = 'pb_Connect';
            app.ConnectButton.Text = 'Connect';
            app.RefreshCOMButton = uibutton(app.ConnectionPanel, 'push');
            app.RefreshCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMButtonPushed, true);
            app.RefreshCOMButton.Text = 'Refresh COM';
            app.ChooseCOMDropDownLabel = uilabel(app.ConnectionPanel);
            app.ChooseCOMDropDownLabel.Text = 'Choose COM';
            app.ChooseCOMDropDown = uidropdown(app.ConnectionPanel);
            app.ChooseCOMDropDown.Items = {};
            app.ChooseCOMDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseCOMDropDownOpening, true);
            app.ChooseCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCOMDropDownValueChanged, true);
            app.ChooseCOMDropDown.Tag = 'dd_COM';
            app.ChooseCOMDropDown.Value = {};
            app.PatternRecognitionClassifierPanel = uipanel(app.ClassifierParametersTab);
            app.PatternRecognitionClassifierPanel.Title = 'Pattern Recognition Classifier';
            app.LoadfromfileButton = uibutton(app.PatternRecognitionClassifierPanel, 'push');
            app.LoadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.LoadfromfileButton.Text = 'Load from file';
            app.ClassifierLabel = uilabel(app.PatternRecognitionClassifierPanel);
            app.ClassifierLabel.Text = 'Classifier';
            app.FeaturesLabel = uilabel(app.PatternRecognitionClassifierPanel);
            app.FeaturesLabel.Text = 'Features';
            app.MagFeaturesLabel = uilabel(app.PatternRecognitionClassifierPanel);
            app.MagFeaturesLabel.Text = 'Mag. Features';
            app.NumclassesLabel = uilabel(app.PatternRecognitionClassifierPanel);
            app.NumclassesLabel.Text = 'Num. classes';
            app.UploadtoboardButton = uibutton(app.PatternRecognitionClassifierPanel, 'push');
            app.UploadtoboardButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtoboardButtonPushed, true);
            app.UploadtoboardButton.Enable = 'off';
            app.UploadtoboardButton.Text = 'Upload to board';
            app.ProportionalcontrolCheckBox = uicheckbox(app.PatternRecognitionClassifierPanel);
            app.ProportionalcontrolCheckBox.Text = 'Proportional control';
            app.UploadpropctrlparamButton = uibutton(app.PatternRecognitionClassifierPanel, 'push');
            app.UploadpropctrlparamButton.ButtonPushedFcn = createCallbackFcn(app, @UploadpropctrlparamButtonPushed, true);
            app.UploadpropctrlparamButton.Enable = 'off';
            app.UploadpropctrlparamButton.Text = 'Upload prop. ctrl. param.';
            app.FeaturessettingsPanel = uipanel(app.ClassifierParametersTab);
            app.FeaturessettingsPanel.Title = 'Features settings';
            app.TimewindowmsEditFieldLabel = uilabel(app.FeaturessettingsPanel);
            app.TimewindowmsEditFieldLabel.Text = 'Time window (ms)';
            app.TimewindowmsEditField = uieditfield(app.FeaturessettingsPanel, 'text');
            app.TimewindowmsEditField.Value = '200';
            app.OverlapwindowsamplesEditFieldLabel = uilabel(app.FeaturessettingsPanel);
            app.OverlapwindowsamplesEditFieldLabel.Text = 'Overlap window (samples)';
            app.OverlapwindowsamplesEditField = uieditfield(app.FeaturessettingsPanel, 'text');
            app.OverlapwindowsamplesEditField.Enable = 'off';
            app.OverlapwindowsamplesEditField.Value = '1';
            app.SendfeatparamButton = uibutton(app.FeaturessettingsPanel, 'push');
            app.SendfeatparamButton.ButtonPushedFcn = createCallbackFcn(app, @SendfeatparamButtonPushed, true);
            app.SendfeatparamButton.Text = 'Send feat. param.';
            app.FeaturesPanel = uipanel(app.FeaturessettingsPanel);
            app.FeaturesPanel.Title = 'Features';
            app.MAVCheckBox = uicheckbox(app.FeaturesPanel);
            app.MAVCheckBox.Enable = 'off';
            app.MAVCheckBox.Text = 'MAV';
            app.MedianCheckBox = uicheckbox(app.FeaturesPanel);
            app.MedianCheckBox.Enable = 'off';
            app.MedianCheckBox.Text = 'Median';
            app.MaxCheckBox = uicheckbox(app.FeaturesPanel);
            app.MaxCheckBox.Enable = 'off';
            app.MaxCheckBox.Text = 'Max';
            app.MinCheckBox = uicheckbox(app.FeaturesPanel);
            app.MinCheckBox.Enable = 'off';
            app.MinCheckBox.Text = 'Min';
            app.WLCheckBox = uicheckbox(app.FeaturesPanel);
            app.WLCheckBox.Enable = 'off';
            app.WLCheckBox.Text = 'WL';
            app.SlopeCheckBox = uicheckbox(app.FeaturesPanel);
            app.SlopeCheckBox.Enable = 'off';
            app.SlopeCheckBox.Text = 'Slope';
            app.LastvalueCheckBox = uicheckbox(app.FeaturesPanel);
            app.LastvalueCheckBox.Enable = 'off';
            app.LastvalueCheckBox.Text = 'Last value';
            app.WaitforODA150msEditField_2Label = uilabel(app.FeaturessettingsPanel);
            app.WaitforODA150msEditField_2Label.Text = 'Wait for ODA (1 = 50ms)';
            app.WaitforODAEditField = uieditfield(app.FeaturessettingsPanel, 'numeric');
            app.WaitforODAEditField.Value = 1;
            app.WaitforMV150msEditFieldLabel = uilabel(app.FeaturessettingsPanel);
            app.WaitforMV150msEditFieldLabel.Text = 'Wait for MV (1 = 50ms)';
            app.WaitforMVEditField = uieditfield(app.FeaturessettingsPanel, 'numeric');
            app.WaitforMVEditField.Value = 5;
            app.AllowpatternrecognitionButton = uibutton(app.FeaturessettingsPanel, 'state');
            app.AllowpatternrecognitionButton.ValueChangedFcn = createCallbackFcn(app, @AllowpatternrecognitionButtonValueChanged, true);
            app.AllowpatternrecognitionButton.Text = 'Allow pattern recognition';
            app.PostprocessingalgorithmButtonGroup = uibuttongroup(app.FeaturessettingsPanel);
            app.PostprocessingalgorithmButtonGroup.Title = 'Post-processing algorithm';
            app.NoneButton = uiradiobutton(app.PostprocessingalgorithmButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.MajorityvoteButton = uiradiobutton(app.PostprocessingalgorithmButtonGroup);
            app.MajorityvoteButton.Text = 'Majority vote';
            app.GrasplockButton = uiradiobutton(app.PostprocessingalgorithmButtonGroup);
            app.GrasplockButton.Text = 'Grasp lock';
            app.StreamingTab = uitab(app.TabGroup);
            app.StreamingTab.Title = 'Streaming';
            app.StreamButton = uibutton(app.StreamingTab, 'state');
            app.StreamButton.ValueChangedFcn = createCallbackFcn(app, @StreamButtonValueChanged, true);
            app.StreamButton.Text = 'Stream';
            app.PredictedclassLabel = uilabel(app.StreamingTab);
            app.PredictedclassLabel.Text = 'Predicted class';
            app.ClassLabel = uilabel(app.StreamingTab);
            app.ClassLabel.Text = 'Class';
            app.ProportionalcontrolLabel = uilabel(app.StreamingTab);
            app.ProportionalcontrolLabel.Text = 'Proportional control (%)';
            app.ForceLabel = uilabel(app.StreamingTab);
            app.ForceLabel.Text = 'Force';
            app.ClassifierLabel_2 = uilabel(app.StreamingTab);
            app.ClassifierLabel_2.Text = 'Classifier';
            app.TypeLabel = uilabel(app.StreamingTab);
            app.TypeLabel.Text = 'Type';
            app.dist1Label = uilabel(app.StreamingTab);
            app.dist1Label.Text = 'dist1';
            app.dist2Label = uilabel(app.StreamingTab);
            app.dist2Label.Text = 'dist2';
            app.dist3Label = uilabel(app.StreamingTab);
            app.dist3Label.Text = 'dist3';
            app.PostprocessingalgorithmLabel = uilabel(app.StreamingTab);
            app.PostprocessingalgorithmLabel.Text = 'Post-processing algorithm';
            app.PPTypeLabel = uilabel(app.StreamingTab);
            app.PPTypeLabel.Text = 'Type';
            app.RestandMaxdispTab = uitab(app.TabGroup);
            app.RestandMaxdispTab.Title = 'Rest and Max disp';
            app.LoadDataButton = uibutton(app.RestandMaxdispTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.MagnetPairsLabel = uilabel(app.RestandMaxdispTab);
            app.MagnetPairsLabel.Text = 'Magnet Pairs';
            app.MagnetPairs = uilabel(app.RestandMaxdispTab);
            app.MagnetPairs.Text = 'Pair: (Mag1, Mag2)';
            app.RestdistanceLabel = uilabel(app.RestandMaxdispTab);
            app.RestdistanceLabel.Text = 'Rest distance';
            app.RestDistance = uilabel(app.RestandMaxdispTab);
            app.RestDistance.Text = 'Pair: distance';
            app.MaximumdisplacementLabel = uilabel(app.RestandMaxdispTab);
            app.MaximumdisplacementLabel.Text = 'Maximum displacement';
            app.MaximumDisplacement = uilabel(app.RestandMaxdispTab);
            app.MaximumDisplacement.Text = 'Pair: displacement';
            app.UploadDataButton = uibutton(app.RestandMaxdispTab, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Enable = 'off';
            app.UploadDataButton.Text = 'Upload Data';
            app.UploadMagnetPairsButton = uibutton(app.RestandMaxdispTab, 'push');
            app.UploadMagnetPairsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMagnetPairsButtonPushed, true);
            app.UploadMagnetPairsButton.Enable = 'off';
            app.UploadMagnetPairsButton.Text = 'Upload Magnet Pairs';
            app.MultilinearRegressorTab = uitab(app.TabGroup);
            app.MultilinearRegressorTab.Title = 'Multilinear Regressor';
            app.LoadfeaturesButton = uibutton(app.MultilinearRegressorTab, 'push');
            app.LoadfeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfeaturesButtonPushed, true);
            app.LoadfeaturesButton.Text = 'Load features';
            app.FeaturesListBoxLabel = uilabel(app.MultilinearRegressorTab);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.MultilinearRegressorTab);
            app.FeaturesListBox.Items = {};
            app.FeaturesListBox.Multiselect = 'on';
            app.FeaturesListBox.Value = {};
            app.DoFListBoxLabel = uilabel(app.MultilinearRegressorTab);
            app.DoFListBoxLabel.Text = 'DoF';
            app.DoFListBox = uilistbox(app.MultilinearRegressorTab);
            app.DoFListBox.Items = {};
            app.DoFListBox.Multiselect = 'on';
            app.DoFListBox.Value = {};
            app.TrainMLRButton = uibutton(app.MultilinearRegressorTab, 'push');
            app.TrainMLRButton.ButtonPushedFcn = createCallbackFcn(app, @TrainMLRButtonPushed, true);
            app.TrainMLRButton.Enable = 'off';
            app.TrainMLRButton.Text = 'Train MLR';
            app.TestMLRButton = uibutton(app.MultilinearRegressorTab, 'push');
            app.TestMLRButton.Enable = 'off';
            app.TestMLRButton.Text = 'Test MLR';
            app.UploadparametersButton = uibutton(app.MultilinearRegressorTab, 'push');
            app.UploadparametersButton.Enable = 'off';
            app.UploadparametersButton.Text = 'Upload parameters';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Tag = 't_msg';
            app.WelcomeLabel.Text = 'Welcome';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MYKI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end